classdef imageprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        OperationsPanel     matlab.ui.container.Panel
        DataRetrivalButton  matlab.ui.control.Button
        DataHidingButton_2  matlab.ui.control.Button
        Image               matlab.ui.control.Image
        IMAGELabel          matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DataHidingButton_2
        function DataHidingButton_2Pushed(app, event)

         %browsing the message image.
         [filename,pathname]=uigetfile('*.jpg','File Selector');
         name=strcat(pathname,filename);
         a=imread(name); % message image
         b=imread(app.Image.ImageSource);% cover image.
         msg=imbinarize(rgb2gray(a));% converted the message image to binary image.
         imshow(a,'Parent',app.UIAxes);
         %resizing message image to size of base image.
         msg=imresize(msg,size(b(:,:,1)));
         % hiding the image.
         new=b;
         new(:,:,1)=bitset(new(:,:,1),1,msg);
         imshow(new,'Parent',app.UIAxes_2);
         %to save the watermarked image.
         imwrite(new,'watermarked.bmp');
           
             
        end

        % Button pushed function: DataRetrivalButton
        function DataRetrivalButtonPushed(app, event)
            
            wm=imread('watermarked.bmp');
            message= bitget(wm(:,:,1),1);
            % converting the image to rgb
            message=double(message);
            message(:,:,1)=bitset(message(:,:,1),16,message);
            %showing the hidden image
            imshow(message,'Parent',app.UIAxes2);


        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Original Stego image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'hidden image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.IMAGELabel = uilabel(app.UIFigure);
            app.IMAGELabel.Text = 'IMAGE';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'rose.jpg');
            app.OperationsPanel = uipanel(app.UIFigure);
            app.OperationsPanel.Title = 'Operations';
            app.DataHidingButton_2 = uibutton(app.OperationsPanel, 'push');
            app.DataHidingButton_2.ButtonPushedFcn = createCallbackFcn(app, @DataHidingButton_2Pushed, true);
            app.DataHidingButton_2.Text = 'Data Hiding';
            app.DataRetrivalButton = uibutton(app.OperationsPanel, 'push');
            app.DataRetrivalButton.ButtonPushedFcn = createCallbackFcn(app, @DataRetrivalButtonPushed, true);
            app.DataRetrivalButton.Text = 'Data Retrival';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageprocessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end